classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Load_data           matlab.ui.control.Button
        GenerateButton      matlab.ui.control.Button
        SizeEditField       matlab.ui.control.NumericEditField
        EditFieldLabel      matlab.ui.control.Label
        EditField2          matlab.ui.control.EditField
        EditField2Label     matlab.ui.control.Label
        CoNNECT_CheckBox    matlab.ui.control.CheckBox
        GLMCC_CheckBox      matlab.ui.control.CheckBox
        Stark_CheckBox      matlab.ui.control.CheckBox
        Panel               matlab.ui.container.Panel
        click_neuron_pairs  matlab.ui.control.Label
        MethodButtonGroup   matlab.ui.container.ButtonGroup
        CoNNECTButton       matlab.ui.control.RadioButton
        GLMCCButton         matlab.ui.control.RadioButton
        StarkButton         matlab.ui.control.RadioButton
        Data_path           matlab.ui.control.Label
        HeatmapAxes         matlab.ui.control.UIAxes
        ImageAxes           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        HeatmapData % Description
        data
        CCG_data
    end

    methods (Access = private)
        function plot_heatmap(app)
            %             imagesc(app.HeatmapAxes, app.HeatmapData,"HitTest","off");
    % Imagesc plot on the lower axes
    imagesc(app.HeatmapAxes, 'XData', 1:size(app.HeatmapData, 2), ...
                             'YData', 1:size(app.HeatmapData, 1), ...
                             'CData', app.HeatmapData, ...
                             'HitTest', 'off');
    
    % Set axis properties for the heatmap
    axis(app.HeatmapAxes, 'xy');  % Ensure 'xy' direction for proper display
   % Hide the axis labels and only show the tick marks
    app.HeatmapAxes.XLabel.String = '';
    app.HeatmapAxes.YLabel.String = '';
    app.HeatmapAxes.XColor = 'none';
    app.HeatmapAxes.YColor = 'none';

    title(app.HeatmapAxes, 'Heatmap Title');  % Adjust as needed
        % Adjust the limits and other properties if necessary
    app.HeatmapAxes.XLim = [0, size(app.HeatmapData, 2)];
    app.HeatmapAxes.YLim = [0, size(app.HeatmapData, 1)];
    
        % Set the tick marks from 0 to the size of the matrix
    xticks(app.HeatmapAxes, 0:size(app.HeatmapData, 2));
    yticks(app.HeatmapAxes, 0:size(app.HeatmapData, 1));
        end


        function heatmapClickCallback(app, event)
            % Get the click position
            cp = event.IntersectionPoint;
            row = round(cp(2));
            col = round(cp(1));
            app.click_neuron_pairs.Text=[num2str(row) 'vs' num2str(col) ];

            app.Stark_CheckBox.Value=1;
            app.GLMCC_CheckBox.Value=1;
            app.CoNNECT_CheckBox.Value=1;
            

            % Ensure the click is within the heatmap bounds
            if row > 0 && row <= size(app.HeatmapData, 1) && col > 0 && col <= size(app.HeatmapData, 2)
                % Display an image in the ImageAxes
                % Replace this with actual image data loading if needed
                % Get the size for the heatmap
                N = app.SizeEditField.Value;

                % Generate random heatmap data
                t=linspace(-50,50,301);
                app.CCG_data = app.data.monosyn_data.CCG(:,row,col);
                bar(app.ImageAxes,t,app.CCG_data)
                

                % Plot the heatmap
%                 imagesc(app.HeatmapyAxes, app.HeatmapData);
%                 colorbar(app.ImageAxes);

                %                 img = imread('your_image_file.png'); % Example placeholder
                %                 imshow(img, 'Parent', app.ImageAxes);
                %                 title(app.ImageAxes, ['Cell (' num2str(row) ', ' num2str(col) ')']);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)


            % Get the size for the heatmap
            N = app.SizeEditField.Value;
            app.HeatmapData=rand(10);

            if app.StarkButton.Value==1
%                 app.data.monosyn_data.connection_matrix.starkMatrix
                app.HeatmapData = app.data.monosyn_data.connection_matrix.starkMatrix;
            end
            if app.CoNNECTButton.Value==1
                app.HeatmapData = app.data.monosyn_data.connection_matrix.CoNNECTMatrix;
            end
            if app.GLMCCButton.Value==1
                app.HeatmapData = app.data.monosyn_data.connection_matrix.GLMCCMatrix;
            end

            % Generate random heatmap data
plot_heatmap(app)


            %             heatmap(app.HeatmapAxes,rand(10));
            a=1;
%             colorbar off

            %             h = heatmap(app.UIFigure,rand(10));
            %             h1.ButtonDownFcn = @(src, event) click_callback(src, event, N);
            %             set(app.UIFigure, 'ButtonDownFcn', @click_callback);


            % Function to handle click events
%             function click_callback(src, event)
%                 % Get the coordinates of the click
%                 point = get(gca, 'CurrentPoint');
%                 x = round(point(1, 1));
%                 y = round(point(1, 2));
% 
%                 % Ensure the coordinates are within bounds
%                 if x >= 1 && x <= N && y >= 1 && y <= N
%                     % Display the clicked coordinates
%                     disp(['Clicked at: (', num2str(x), ', ', num2str(y), ')']);
% 
%                     % Generate and display another image (random example)
%                     generate_another_image(x, y);
%                 end
% 
% 
%             end

            % Plot the heatmap
            %             imagesc(app.HeatmapAxes, app.HeatmapData);
            %             colorbar(app.HeatmapAxes);

            % Set up callback for cell click
            %             app.HeatmapAxes.ButtonDownFcn = @(src, event) heatmapClickCallback(app, event);
        end

        % Button down function: HeatmapAxes, Panel
        function HeatmapAxesButtonDown(app, event)

            heatmapClickCallback(app, event)
        end

        % Button pushed function: Load_data
        function Load_dataPushed(app, event)
            %             app.Label2.Text='Waiting...';
            [filename,pathname] = uigetfile('*');
            fullpath = fullfile(pathname,filename);
            app.Data_path.Text=filename;
            app.data=load(fullpath);

            %             databasemaker2_2(fullpath);
            %             app.ego=data_tranform_RUI('cb.txt');
            %             app.Label2.Text='Finished';
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.MethodButtonGroup.SelectedObject;


            if strcmp(selectedButton.Text,'Stark')
                app.HeatmapData = app.data.monosyn_data.connection_matrix.starkMatrix;
            end
                        if strcmp(selectedButton.Text,'GLMCC')
                app.HeatmapData = app.data.monosyn_data.connection_matrix.GLMCCMatrix;
                        end
                                    if strcmp(selectedButton.Text,'CoNNECT')
                app.HeatmapData = app.data.monosyn_data.connection_matrix.CoNNECTMatrix;
                                    end
                                    
plot_heatmap(app);
a=1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'CCG')
            xlabel(app.ImageAxes, 'Time (ms)')
            ylabel(app.ImageAxes, 'Num')
            zlabel(app.ImageAxes, 'Z')
            app.HeatmapAxes = uiaxes(app.UIFigure);
            app.HeatmapAxes.XTick = [];
            app.HeatmapAxes.YTick = [];
            app.HeatmapAxes.ButtonDownFcn = createCallbackFcn(app, @HeatmapAxesButtonDown, true);
            app.Data_path = uilabel(app.UIFigure);
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.StarkButton = uiradiobutton(app.MethodButtonGroup);
            app.StarkButton.Text = 'Stark';
            app.StarkButton.Value = true;
            app.GLMCCButton = uiradiobutton(app.MethodButtonGroup);
            app.GLMCCButton.Text = 'GLMCC';
            app.CoNNECTButton = uiradiobutton(app.MethodButtonGroup);
            app.CoNNECTButton.Text = 'CoNNECT';
            app.click_neuron_pairs = uilabel(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.ButtonDownFcn = createCallbackFcn(app, @HeatmapAxesButtonDown, true);
            app.Stark_CheckBox = uicheckbox(app.UIFigure);
            app.Stark_CheckBox.Text = '';
            app.GLMCC_CheckBox = uicheckbox(app.UIFigure);
            app.GLMCC_CheckBox.Text = '';
            app.CoNNECT_CheckBox = uicheckbox(app.UIFigure);
            app.CoNNECT_CheckBox.Text = '';
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.SizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SizeEditField.Value = 5;
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Estimate';
            app.Load_data = uibutton(app.UIFigure, 'push');
            app.Load_data.ButtonPushedFcn = createCallbackFcn(app, @Load_dataPushed, true);
            app.Load_data.Text = 'Load Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end