classdef NeuronalConnectivityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UploadButton             matlab.ui.control.Button
        MethodDropDownLabel      matlab.ui.control.Label
        MethodDropDown           matlab.ui.control.DropDown
        ParametersPanel          matlab.ui.container.Panel
        TauEditFieldLabel        matlab.ui.control.Label
        TauEditField             matlab.ui.control.NumericEditField
        MinSEditFieldLabel       matlab.ui.control.Label
        MinSEditField            matlab.ui.control.NumericEditField
        DSEditFieldLabel         matlab.ui.control.Label
        DSEditField              matlab.ui.control.EditField
        RunButton                matlab.ui.control.Button
        ResultsAxes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        SpikeTrainData           % Data variable for storing spike train data
        SelectedMethod           % Variable for storing selected method
    end

    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file, path] = uigetfile('*.txt', 'Select Spike Train Data');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                fullPath = fullfile(path, file);
                app.SpikeTrainData = load(fullPath);
                disp(['User selected ', fullPath]);
            end
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            app.SelectedMethod = app.MethodDropDown.Value;
            disp(['Selected Method: ', app.SelectedMethod]);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            tau = app.TauEditField.Value;
            min_s = app.MinSEditField.Value;
            ds = str2num(app.DSEditField.Value);

            % Implement connectivity estimation based on selected method
            results = app.estimate_connectivity(app.SpikeTrainData, tau, min_s, ds, app.SelectedMethod);

            % Display results
            app.plot_results(results);
        end

        function results = estimate_connectivity(app, data, tau, min_s, ds, method)
            % Placeholder function for estimating connectivity
            % Implement the actual algorithm based on the selected method
            results = rand(10); % Replace with actual computation
        end

        function plot_results(app, results)
            % Plot the results in the UIAxes
            plot(app.ResultsAxes, results);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.Text = 'Upload Data';
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Select Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'CoNNECT', 'Revised GLMCC', 'Original GLMCC'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Set Parameters';
            app.TauEditFieldLabel = uilabel(app.ParametersPanel);
            app.TauEditFieldLabel.Text = 'Tau';
            app.TauEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.MinSEditFieldLabel = uilabel(app.ParametersPanel);
            app.MinSEditFieldLabel.Text = 'Min S';
            app.MinSEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DSEditFieldLabel = uilabel(app.ParametersPanel);
            app.DSEditFieldLabel.Text = 'DS';
            app.DSEditField = uieditfield(app.ParametersPanel, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'Run';
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.ResultsAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = public)

        % Construct app
        function app = NeuronalConnectivityApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end